classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        frwagenUIFigure             matlab.ui.Figure
        GuardarButton               matlab.ui.control.Button
        EncontrardiferenciasButton  matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        SeleccionararchivoButton    matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    
    properties (Access = private)
        tabs 
        TabGroup
        nTabs = 1
        UITable
        monitor
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.monitor = get(0,'ScreenSize');
            interfaz = app.frwagenUIFigure.Position;
            x = app.monitor(3)/2-interfaz(3)/2;
            y = app.monitor(4)/2-interfaz(4)/2;
            app.frwagenUIFigure.Position = [x y interfaz(3) interfaz(4)];
            app.frwagenUIFigure.WindowState = 'maximized';
            app.Image.Position = [(app.monitor(3)-340),790,317,47];
        end

        % Button pushed function: SeleccionararchivoButton
        function SeleccionararchivoButtonPushed(app, event)

            try
                [file,path] = uigetfile({'*.csv';'*.txt';'*.xlsx'},'File Selector');
                tabla = readtable(file,VariableNamingRule="preserve");
                app.tabs = cell(1,app.nTabs);
                if app.nTabs == 1
                    app.TabGroup = uitabgroup(app.frwagenUIFigure);
                    app.tabs{app.nTabs} = uitab(app.TabGroup,'Title',file); %create the main tab
                    app.UITable = uitable(app.tabs{app.nTabs});
                    app.UITable.Data = tabla;
                    app.UITable.ColumnName = tabla.Properties.VariableNames;
                    app.TabGroup.Position = [38, 31, (app.monitor(3)-90), (app.monitor(4)-180)];
                    app.UITable.Position = [41, 34, (app.monitor(3)-170), (app.monitor(4)-260)];
                else
                    app.tabs{app.nTabs} = uitab(app.TabGroup,'Title',file );
                    app.UITable = uitable(app.tabs{app.nTabs});
                    app.UITable.Data = tabla;
                    app.UITable.ColumnName = tabla.Properties.VariableNames;
                    app.UITable.Position = [41, 34, (app.monitor(3)-170), (app.monitor(4)-260)];
                end
                app.nTabs = app.nTabs+1;
            catch
                errordlg("El archivo seleccionado es invalido","Error")
            end
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Button pushed function: EncontrardiferenciasButton
        function EncontrardiferenciasButtonPushed(app, event)
            
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            
        end

        % Close request function: frwagenUIFigure
        function frwagenUIFigureCloseRequest(app, event)
             opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.frwagenUIFigure = uifigure('Visible', 'off');
            app.frwagenUIFigure.Name = 'fürwagen';
            app.frwagenUIFigure.Icon = fullfile(pathToMLAPP, 'Manual de marca - Furwagen', 'Logos 2000w', 'Logo V.png');
            app.frwagenUIFigure.CloseRequestFcn = createCallbackFcn(app, @frwagenUIFigureCloseRequest, true);
            app.SeleccionararchivoButton = uibutton(app.frwagenUIFigure, 'push');
            app.SeleccionararchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoButtonPushed, true);
            app.SeleccionararchivoButton.Text = 'Seleccionar archivo';
            app.Image = uiimage(app.frwagenUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Manual de marca - Furwagen', 'Logos 2000w', 'Logo.png');
            app.EncontrardiferenciasButton = uibutton(app.frwagenUIFigure, 'push');
            app.EncontrardiferenciasButton.ButtonPushedFcn = createCallbackFcn(app, @EncontrardiferenciasButtonPushed, true);
            app.EncontrardiferenciasButton.Text = 'Encontrar diferencias';
            app.GuardarButton = uibutton(app.frwagenUIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ContextMenu = uicontextmenu(app.frwagenUIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Image.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.frwagenUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.frwagenUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.frwagenUIFigure)
        end
    end
end